classdef blur_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BC_UI                       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        FocusDistance1mSliderLabel  matlab.ui.control.Label
        fd1_sl                      matlab.ui.control.Slider
        LensParametersPanel         matlab.ui.container.Panel
        PixelSizeumLabel            matlab.ui.control.Label
        px_size_tb                  matlab.ui.control.NumericEditField
        fnumberLabel                matlab.ui.control.Label
        f_num_tb                    matlab.ui.control.NumericEditField
        FocalLengthmmLabel          matlab.ui.control.Label
        focal_length_tb             matlab.ui.control.NumericEditField
        PlotControlPanel            matlab.ui.container.Panel
        XmLabel                     matlab.ui.control.Label
        x_min_tb                    matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        x_max_tb                    matlab.ui.control.NumericEditField
        YpxLabel                    matlab.ui.control.Label
        y_min_tb                    matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        y_max_tb                    matlab.ui.control.NumericEditField
        FocusDistance2mLabel        matlab.ui.control.Label
        fd2_sl                      matlab.ui.control.Slider
        DepthofFieldPanel           matlab.ui.container.Panel
        DNearmEditFieldLabel        matlab.ui.control.Label
        d_near_tb                   matlab.ui.control.NumericEditField
        DFarmEditFieldLabel         matlab.ui.control.Label
        d_far_tb                    matlab.ui.control.NumericEditField
        DoFmEditFieldLabel          matlab.ui.control.Label
        dof_tb                      matlab.ui.control.NumericEditField
        ROIPanel                    matlab.ui.container.Panel
        XmLabel_2                   matlab.ui.control.Label
        roi_x_min_tb                matlab.ui.control.NumericEditField
        EditField_2Label_2          matlab.ui.control.Label
        roi_x_max_tb                matlab.ui.control.NumericEditField
        quantized_cb                matlab.ui.control.CheckBox
        plot_ax                     matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        fd2_tb                      matlab.ui.control.NumericEditField
        Panel_2                     matlab.ui.container.Panel
        fd1_tb                      matlab.ui.control.NumericEditField
        Panel_3                     matlab.ui.container.Panel
        export_radius_btn           matlab.ui.control.Button
        Panel_4                     matlab.ui.container.Panel
        RangeBinmLabel              matlab.ui.control.Label
        range_bin_tb                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CoC1 % Description
        CoC2
        range % Description
        save_path = ''% Description
    end

    methods (Access = private)
    
        %--------------------------------------------------------------------------
        function uifigurepop(app)
            drawnow;
            app.BC_UI.Visible = 'off';
            app.BC_UI.Visible = 'on';
        end    
        
        function update_plot(app)
            
            if(app.x_min_tb.Value > app.x_max_tb.Value)
                app.x_max_tb.Value = app.x_min_tb.Value + 1;
            elseif(app.x_max_tb.Value < app.x_min_tb.Value)
                app.x_min_tb.Value = app.x_min_tb.Limits(1);
            end
            
            if(app.y_min_tb.Value > app.y_max_tb.Value)
                app.y_max_tb.Value = app.y_min_tb.Value + 1;
            elseif(app.y_max_tb.Value < app.y_min_tb.Value)
                app.y_min_tb.Value = app.y_min_tb.Limits(1);
            end
            
            x_limits = [app.x_min_tb.Value, app.x_max_tb.Value];
            y_limits = [app.y_min_tb.Value, app.y_max_tb.Value];
            px_size = app.px_size_tb.Value/1000;
            f_num = app.f_num_tb.Value;
            fl = app.focal_length_tb.Value;
            do1 = app.fd1_sl.Value*1000;
            do2 = app.fd2_sl.Value*1000;
            step = 1000;
            
            do1 = floor(do1/step)*step;
            do2 = floor(do2/step)*step;
            
            coc_range = [0,20000];
            
            y_axis_ticks = [y_limits(1):px_size:(px_size*y_limits(2)+px_size)];
            y_axis_labels = num2str(y_axis_ticks'/px_size);
            
            [app.range, app.CoC1, CoC_max1] = blurCalc(f_num, fl, do1, coc_range*1000, step);          
            [S_range2, app.CoC2, CoC_max2] = blurCalc(f_num, fl, do2, coc_range*1000, step);
            
            if(numel(app.CoC1) == numel(app.CoC2))
                D = abs(app.CoC1 - app.CoC2);
            else
                D = zeros(1,numel(S_range2));
            end
            
            if(app.quantized_cb.Value == true)
                coc1 = ceil(app.CoC1/px_size);
                coc2 = ceil(app.CoC2/px_size);
                D = abs(coc1-coc2);
                
                hold(app.plot_ax, 'off');
                plot(app.plot_ax, app.range/1000, coc1,'.-b');
                hold(app.plot_ax, 'on');
                plot(app.plot_ax, S_range2/1000, coc2,'.-g');
                plot(app.plot_ax, S_range2/1000, D,'.-k');
                
                xlim(app.plot_ax, x_limits);
                ylim(app.plot_ax, y_limits);
                
                y_axis_ticks = [y_limits(1):1:y_limits(2)];
                yticks(app.plot_ax, y_axis_ticks);
                %yticklabels(app.plot_ax, y_axis_labels);                
                
            else
                hold(app.plot_ax, 'off');
                plot(app.plot_ax, app.range/1000, app.CoC1,'.-b');
                hold(app.plot_ax, 'on');
                plot(app.plot_ax, S_range2/1000, app.CoC2,'.-g');
                plot(app.plot_ax, S_range2/1000, D,'.-k');
                
                xlim(app.plot_ax, x_limits);
                ylim(app.plot_ax, y_limits*px_size);
                
                yticks(app.plot_ax, y_axis_ticks);
                yticklabels(app.plot_ax, y_axis_labels);
            end
            
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_plot(app);
        end

        % Value changed function: fd1_sl, fd2_sl
        function fd_slValueChanged(app, event)
            
            app.fd1_tb.Value = app.fd1_sl.Value;
            app.fd2_tb.Value = app.fd2_sl.Value;
            
            update_plot(app);
            
        end

        % Value changed function: quantized_cb, x_max_tb, x_min_tb, 
        % y_max_tb, y_min_tb
        function plot_control_ValueChanged(app, event)
            update_plot(app);
            
        end

        % Value changed function: f_num_tb, focal_length_tb, 
        % px_size_tb
        function lens_param_ValueChanged(app, event)
            update_plot(app);
            
        end

        % Value changed function: fd1_tb, fd2_tb
        function fd_tbValueChanged(app, event)
            app.fd1_sl.Value = app.fd1_tb.Value;
            app.fd2_sl.Value = app.fd2_tb.Value;
            
            update_plot(app);
            
        end

        % Button pushed function: export_radius_btn
        function export_radius_btnButtonPushed(app, event)
            file_filter = {'*.csv','CSV Files';'*.*','All Files' };
            [save_file, tmp_save_path] = uiputfile(file_filter, 'Enter File Name', app.save_path);
            
            if(tmp_save_path == 0)
                return;
            end
            
            steps = app.range_bin_tb.Value;
            app.save_path = tmp_save_path;
            file_id = fopen(fullfile(app.save_path, save_file), 'w');
            
            px_size = app.px_size_tb.Value/1000;

            r = floor(app.range/1000);
            x1 = app.roi_x_min_tb.Value;
            x2 = app.roi_x_max_tb.Value;
            
            index = find((r>=x1) & (r<=x2));
            
            param_str = strcat('# pixel size (mm):', 32, num2str(px_size, '%2.6f'),...
                                 ', f number:', 32, num2str(app.f_num_tb.Value, '%2.3f'),...
                                 ', focal length (mm):', 32, num2str(app.focal_length_tb.Value, '%4.3f'),...
                                 ', fp1 (m):', 32, num2str(app.fd1_tb.Value), ', fp2 (m):', 32, num2str(app.fd2_sl.Value));
            fp1_str = '# br1 = [';
            fp2_str = '# br2 = [';
            range_str = '# range:, ';
            diff_str = '# diff:, ';

            
            for idx=1:steps:numel(index)
                range_str = strcat(range_str, num2str(r(index(idx)), '%d'), ',');
                fp1_str = strcat(fp1_str, num2str(ceil(app.CoC1(index(idx))/px_size),'%d'), ',');
                fp2_str = strcat(fp2_str, num2str(ceil(app.CoC2(index(idx))/px_size),'%d'), ',');
                diff_str = strcat(diff_str, num2str(abs(ceil(app.CoC1(index(idx))/px_size) - ceil(app.CoC2(index(idx))/px_size)), '%d'), ',');
                %fprintf(file_id, '%d, %d, %d, %d\n', r(index(idx)), ceil(app.CoC1(index(idx))/px_size), ceil(app.CoC2(index(idx))/px_size), abs(ceil(app.CoC1(index(idx))/px_size) - ceil(app.CoC2(index(idx))/px_size)));
            end
            
            fprintf(file_id, '%s\n', param_str);
            fprintf(file_id,'%s\n',range_str(1:end-1));
            fprintf(file_id,'%s];\n',fp1_str(1:end-1));
            fprintf(file_id,'%s];\n',fp2_str(1:end-1));
            fprintf(file_id,'%s\n',diff_str(1:end-1));
            
            fprintf(file_id,'\n# focus point 1\n');
            r = 1;
            for idx=1:steps:numel(index)
                fprintf(file_id, '%1.2f, %2.5f\n', r, ceil(app.CoC1(index(idx))/px_size)*px_size);
                r = r + 1;
            end
            
            fprintf(file_id,'\n# focus point 2\n');
                       
            r = 1;
            for idx=1:steps:numel(index)
                fprintf(file_id, '%1.2f, %2.5f\n', r, ceil(app.CoC2(index(idx))/px_size)*px_size);
                r = r + 1;
            end   
            
            fclose(file_id);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BC_UI = uifigure('Visible', 'off');
            app.BC_UI.Name = 'Blur Calculator';
            app.GridLayout = uigridlayout(app.BC_UI);
            app.FocusDistance1mSliderLabel = uilabel(app.GridLayout);
            app.FocusDistance1mSliderLabel.Text = 'Focus Distance 1 (m):';
            app.fd1_sl = uislider(app.GridLayout);
            app.fd1_sl.Limits = [0 20000];
            app.fd1_sl.MajorTicks = [0 2500 5000 7500 10000 12500 15000 17500 20000];
            app.fd1_sl.MajorTickLabels = {'0', '2500', '5000', '7500', '10000', '12500', '15000', '17500', '20000'};
            app.fd1_sl.ValueChangedFcn = createCallbackFcn(app, @fd_slValueChanged, true);
            app.fd1_sl.Value = 10;
            app.LensParametersPanel = uipanel(app.GridLayout);
            app.LensParametersPanel.Title = 'Lens Parameters';
            app.PixelSizeumLabel = uilabel(app.LensParametersPanel);
            app.PixelSizeumLabel.Text = 'Pixel Size (um):';
            app.px_size_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.px_size_tb.Limits = [0 10];
            app.px_size_tb.ValueDisplayFormat = '%.3f';
            app.px_size_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.px_size_tb.Value = 1.55;
            app.fnumberLabel = uilabel(app.LensParametersPanel);
            app.fnumberLabel.Text = 'f-number:';
            app.f_num_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.f_num_tb.Limits = [0 Inf];
            app.f_num_tb.ValueDisplayFormat = '%.2f';
            app.f_num_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.f_num_tb.Value = 3.7;
            app.FocalLengthmmLabel = uilabel(app.LensParametersPanel);
            app.FocalLengthmmLabel.Text = 'Focal Length (mm):';
            app.focal_length_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.focal_length_tb.Limits = [0 Inf];
            app.focal_length_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.focal_length_tb.Value = 9.6;
            app.PlotControlPanel = uipanel(app.GridLayout);
            app.PlotControlPanel.Title = 'Plot Control';
            app.XmLabel = uilabel(app.PlotControlPanel);
            app.XmLabel.Text = 'X (m):';
            app.x_min_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.x_min_tb.Limits = [0 20000];
            app.x_min_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.EditField_2Label = uilabel(app.PlotControlPanel);
            app.EditField_2Label.Text = '';
            app.x_max_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.x_max_tb.Limits = [0 20000];
            app.x_max_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.x_max_tb.Value = 1000;
            app.YpxLabel = uilabel(app.PlotControlPanel);
            app.YpxLabel.Text = 'Y (px):';
            app.y_min_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.y_min_tb.Limits = [0 20000];
            app.y_min_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.Label = uilabel(app.PlotControlPanel);
            app.Label.Text = '';
            app.y_max_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.y_max_tb.Limits = [0 20000];
            app.y_max_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.y_max_tb.Value = 40;
            app.FocusDistance2mLabel = uilabel(app.GridLayout);
            app.FocusDistance2mLabel.Text = 'Focus Distance 2 (m):';
            app.fd2_sl = uislider(app.GridLayout);
            app.fd2_sl.Limits = [0 20000];
            app.fd2_sl.MajorTicks = [0 2500 5000 7500 10000 12500 15000 17500 20000];
            app.fd2_sl.MajorTickLabels = {'0', '2500', '5000', '7500', '10000', '12500', '15000', '17500', '20000'};
            app.fd2_sl.ValueChangedFcn = createCallbackFcn(app, @fd_slValueChanged, true);
            app.fd2_sl.Value = 2000;
            app.DepthofFieldPanel = uipanel(app.GridLayout);
            app.DepthofFieldPanel.Title = 'Depth of Field';
            app.DNearmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DNearmEditFieldLabel.Text = 'D Near (m):';
            app.d_near_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.DFarmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DFarmEditFieldLabel.Text = 'D Far (m):';
            app.d_far_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.DoFmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DoFmEditFieldLabel.Text = 'DoF (m):';
            app.dof_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.ROIPanel = uipanel(app.GridLayout);
            app.ROIPanel.Title = 'ROI';
            app.XmLabel_2 = uilabel(app.ROIPanel);
            app.XmLabel_2.Text = 'X (m):';
            app.roi_x_min_tb = uieditfield(app.ROIPanel, 'numeric');
            app.roi_x_min_tb.Limits = [0 Inf];
            app.EditField_2Label_2 = uilabel(app.ROIPanel);
            app.EditField_2Label_2.Text = '';
            app.roi_x_max_tb = uieditfield(app.ROIPanel, 'numeric');
            app.roi_x_max_tb.Limits = [0 Inf];
            app.roi_x_max_tb.Value = 1000;
            app.quantized_cb = uicheckbox(app.GridLayout);
            app.quantized_cb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.quantized_cb.Text = 'Quantized';
            app.quantized_cb.Value = true;
            app.plot_ax = uiaxes(app.GridLayout);
            xlabel(app.plot_ax, 'Range (m)')
            ylabel(app.plot_ax, 'Pixels')
            app.Panel = uipanel(app.GridLayout);
            app.fd2_tb = uieditfield(app.Panel, 'numeric');
            app.fd2_tb.Limits = [0 20000];
            app.fd2_tb.RoundFractionalValues = 'on';
            app.fd2_tb.ValueDisplayFormat = '%.0f';
            app.fd2_tb.ValueChangedFcn = createCallbackFcn(app, @fd_tbValueChanged, true);
            app.fd2_tb.Value = 2000;
            app.Panel_2 = uipanel(app.GridLayout);
            app.fd1_tb = uieditfield(app.Panel_2, 'numeric');
            app.fd1_tb.Limits = [0 20000];
            app.fd1_tb.RoundFractionalValues = 'on';
            app.fd1_tb.ValueDisplayFormat = '%.0f';
            app.fd1_tb.ValueChangedFcn = createCallbackFcn(app, @fd_tbValueChanged, true);
            app.fd1_tb.Value = 10;
            app.Panel_3 = uipanel(app.GridLayout);
            app.export_radius_btn = uibutton(app.Panel_3, 'push');
            app.export_radius_btn.ButtonPushedFcn = createCallbackFcn(app, @export_radius_btnButtonPushed, true);
            app.export_radius_btn.Text = 'Export Blur Radius';
            app.Panel_4 = uipanel(app.GridLayout);
            app.RangeBinmLabel = uilabel(app.Panel_4);
            app.RangeBinmLabel.Text = 'Range Bin (m):';
            app.range_bin_tb = uieditfield(app.Panel_4, 'numeric');
            app.range_bin_tb.Limits = [1 20000];
            app.range_bin_tb.Value = 10;
            % Show the figure after all components are created
            app.BC_UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blur_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BC_UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BC_UI)
        end
    end
end