classdef data_histogram_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DepthMapDistributionToolUIFigure  matlab.ui.Figure
        plt_hist                 matlab.ui.control.UIAxes
        TrainingDataLabel        matlab.ui.control.Label
        lb_train_data            matlab.ui.control.ListBox
        btn_load                 matlab.ui.control.Button
        btn_process              matlab.ui.control.Button
        PlotLowerLimitLabel      matlab.ui.control.Label
        sl_left                  matlab.ui.control.Slider
        PlotUpperLimitLabel      matlab.ui.control.Label
        sl_right                 matlab.ui.control.Slider
        MaxBinEditFieldLabel     matlab.ui.control.Label
        nb_maxbin                matlab.ui.control.NumericEditField
        MinBinEditFieldLabel     matlab.ui.control.Label
        nb_minbin                matlab.ui.control.NumericEditField
        PlotStepEditFieldLabel   matlab.ui.control.Label
        nb_plot_step             matlab.ui.control.NumericEditField
        TestDataLabel            matlab.ui.control.Label
        lb_test_data             matlab.ui.control.ListBox
        btn_save                 matlab.ui.control.Button
        TrainInputFileLabel      matlab.ui.control.Label
        train_input_file_tb      matlab.ui.control.EditField
        SaveMatButton            matlab.ui.control.Button
        TiledDistribtionButton   matlab.ui.control.Button
        TiledRowsEditFieldLabel  matlab.ui.control.Label
        TiledRowsEditField       matlab.ui.control.NumericEditField
        TiledColsEditFieldLabel  matlab.ui.control.Label
        TiledColsEditField       matlab.ui.control.NumericEditField
        load_test_data_btn       matlab.ui.control.Button
        TestInputFileLabel       matlab.ui.control.Label
        test_input_file_tb       matlab.ui.control.EditField
        log_scale_cb             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        train_data_path % Description
        save_path % Description
        norm_train_hist % Description
        norm_test_hist % Description
        sum_norm_train_hist % Description
        sum_norm_test_hist % Description
        hist_bin_step % Description
        train_dm_files % Description
        test_dm_files % Description
        train_hist % Description
        test_hist %
        train_hist_sum % Description
        test_hist_sum % Description
        train_scene_name % Description
        test_data_path % Description
    end

    methods (Access = private)
    
        function plot_hist(app)
            min_bin = app.nb_minbin.Value;
            max_bin = app.nb_maxbin.Value;

            plot_step = app.nb_plot_step.Value;
            
            %combined_hist = [app.sum_norm_train_hist; app.sum_norm_test_hist]';
            combined_hist = [app.train_hist_sum; app.test_hist_sum]';


            max_hist_value = 10^ceil(log10(max(combined_hist(:))));
            
            b = bar(app.plt_hist,[min_bin:app.hist_bin_step:max_bin], combined_hist);
            
            % X-Axis
            xlim(app.plt_hist,[min_bin-2, max_bin+2]);
            xticks(app.plt_hist,[min_bin:plot_step:max_bin]);
            xticklabels(app.plt_hist,[min_bin:plot_step:max_bin]);
            xtickangle(app.plt_hist,90);
            xlabel(app.plt_hist,strcat('Depth Map Value'),'fontweight','bold','FontSize',14);
            
            % Y-Axis          
            if(app.log_scale_cb.Value == true)
                y_m = ceil(log10(max(combined_hist(:))));
                y_max = 10^y_m;                 
                ylim(app.plt_hist,[1 y_max]);     % ylim(app.plt_hist,[0 ceil(y_max*100)/100]);
                yticks(app.plt_hist, 10.^[0:1:y_m]);
                y_labels = cellstr(num2str(round(log10(app.plt_hist.YTick')), '10^{%d}'));
                yticklabels(app.plt_hist, y_labels);
                set(app.plt_hist,'yscale','log');
            else
                step_num = 15;
                y_m = max(combined_hist(:));
                y_step = y_m/step_num;                
                y_step = ceil(y_step/10^(floor(log10(y_step))))*10^(floor(log10(y_step)));
                
                %y_m = floor(log10(y_m));
                y_max = y_step*(ceil(y_m/y_step));  %ceil(max(combined_hist(:))/(10^(y_m-1)))*(10^(y_m-1));
                %y_step = ceil(y_max/(10*(10^(y_m))))*(10^(y_m));
                %ylim(app.plt_hist,[10^(y_m-1) y_max]);     % ylim(app.plt_hist,[0 ceil(y_max*100)/100]);
                ylim(app.plt_hist,[y_step y_max]);     % ylim(app.plt_hist,[0 ceil(y_max*100)/100]);
                yticks(app.plt_hist, [y_step:y_step:y_max]);
                y_labels = cellstr(num2str(app.plt_hist.YTick', '%d'));
                yticklabels(app.plt_hist, y_labels);                
                set(app.plt_hist,'yscale','linear');                               
            end
            ylabel(app.plt_hist,'Depth Map Value Count','fontweight','bold','FontSize',14);
            
            b(1).FaceColor = 'b';
            b(2).FaceColor = 'r';
            
            title(app.plt_hist,'Depth Map Distribution', 'fontweight','bold','FontSize',16);
            legend(app.plt_hist,'Training Data','Testing Data', 'location','southoutside', 'Orientation',"horizontal")
                      
            app.sl_left.Value = 0;
            app.sl_right.Value = max_bin;            
        end
    
%------------------------------------------------------------------------------        

        function gen_hist(app)
            min_bin = app.nb_minbin.Value;
            max_bin = app.nb_maxbin.Value+1;
            hist_bins = min_bin:app.hist_bin_step:max_bin;
            app.sl_left.Limits = [0,max_bin];
            app.sl_right.Limits = [0,max_bin];
            
            app.norm_train_hist = [];
            app.train_hist = [];
            app.train_hist_sum = [];
            
            % load depth maps
            for idx=1:length(app.train_dm_files)
                
                %tmp = read_binary_lidar_data(fullfile(app.train_data_path,app.train_dm_files{idx}));
                tmp = imread(fullfile(app.train_data_path,app.train_dm_files{idx}));
                tmp = tmp(:);
                %tmp_size = size(tmp);
                
                for jdx=1:app.nb_maxbin.Value+1
                    app.train_hist(idx,jdx) = sum(tmp==(jdx-1));        
                end
                
                %app.train_hist(idx,:) = histcounts(tmp(:),hist_bins);
                app.train_hist_sum(idx,1) = numel(tmp(:));      %tmp_size(1)*tmp_size(2);
                app.norm_train_hist(idx,:) = app.train_hist(idx,:)/app.train_hist_sum(idx,1);   % histcounts(tmp(:),hist_bins)/(tmp_size(1)*tmp_size(2));
                                
            end   
            
            app.sum_norm_train_hist = [sum(app.norm_train_hist,1)];
            app.train_hist_sum = [sum(app.train_hist,1)];
            
            app.norm_test_hist = [];
            app.test_hist = [];
            app.test_hist_sum = [];
            
            if(~isempty(app.lb_test_data.Value))
                %app.test_dm_files = app.lb_train_data.Items(app.lb_train_data.Value);
                for idx=1:length(app.test_dm_files)  
                    %tmp = read_binary_lidar_data(fullfile(app.train_data_path,app.test_dm_files{idx}));
                    tmp = imread(fullfile(app.test_data_path,app.test_dm_files{idx}));
                    tmp = tmp(:);
                    %tmp_size = size(tmp);
                    
                    for jdx=1:app.nb_maxbin.Value+1
                        app.test_hist(idx,jdx) = sum(tmp==(jdx-1));        
                    end
                    
                    %app.test_hist(idx,:) = histcounts(tmp(:),hist_bins);
                    app.test_hist_sum(idx,1) = numel(tmp(:));
                    app.norm_test_hist(idx,:) = app.test_hist(idx,jdx)/app.test_hist_sum(idx,1);    %histcounts(tmp(:),hist_bins)/(tmp_size(1)*tmp_size(2));
                    
                end
                %app.sum_test_hist = [sum(app.norm_test_hist)];
            else
                app.norm_test_hist = zeros(size(app.sum_norm_train_hist));
                app.test_hist = zeros(size(app.sum_norm_train_hist));
            end
            
            if(size(app.norm_test_hist,1) > 1)
                app.sum_norm_test_hist = sum(app.norm_test_hist);
                app.test_hist_sum = sum(app.test_hist);
            else
                app.sum_norm_test_hist = app.norm_test_hist; 
                app.test_hist_sum = app.test_hist;
            end
            
        end
    
    %--------------------------------------------------------------------------

        function uifigurepop(app)
            drawnow;
            app.DepthMapDistributionToolUIFigure.Visible = 'off';
            app.DepthMapDistributionToolUIFigure.Visible = 'on';
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.save_path = 'D:\IUPUI\PhD\Images\datasets';
            app.train_data_path = 'D:/IUPUI/DfD/dfd_dnn_rw/inputs';
            app.test_data_path = 'D:/IUPUI/DfD/dfd_dnn_rw/inputs';            
        end

        % Button pushed function: btn_load
        function btn_loadButtonPushed(app, event)
            file_filter = {'*.txt','Text Files';'*.*','All Files' };
            
            %app.train_data_path = 'D:/IUPUI/DfD/dfd_dnn_rw/inputs';
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.train_data_path);
            if(input_path == 0)
                return;
            end
            
            uifigurepop(app);
            
            app.train_input_file_tb.Value = input_file;
%             app.save_path = uigetdir('D:\IUPUI\PhD\Images\datasets','Select Save Folder');
%             if(app.save_path == 0)
%                 return;
%             end
            
            input_data = parse_input_parameters(fullfile(input_path, input_file));
%            app.train_data_path = strcat(getenv('DATA_HOME'), '/', input_data{1}{1});
            app.train_data_path = input_data{1}{1};
            input_data(1) = [];
            
            tmp = cell(length(input_data),1);

            % get the depth map files
            for idx=1:length(input_data)    
                tmp_file = strsplit(input_data{idx}{3},{'_','.'});
                %tmp{idx,1} = strcat(tmp_file{1},'_',tmp_file{2},'_',tmp_file{3},'_',tmp_file{4},'.bin');
                tmp{idx,1} = input_data{idx}{3};
            end
            dm_files = unique(tmp);
            
            app.lb_train_data.Items = dm_files;
            app.lb_train_data.ItemsData = [1:numel(dm_files)];
            
%             app.lb_test_data.Items = dm_files;
%             app.lb_test_data.ItemsData = [1:numel(dm_files)];
            
        end

        % Button pushed function: btn_process
        function btn_processButtonPushed(app, event)
            app.hist_bin_step = 1;
            min_bin = app.nb_minbin.Value;
            max_bin = app.nb_maxbin.Value+1;
            %hist_bins = min_bin:app.hist_bin_step:max_bin;
            
            %app.sl_left.Limits = [0,max_bin];
            %app.sl_right.Limits = [0,max_bin];
            app.norm_train_hist = [];
            app.norm_test_hist = [];
            %train_scenario_name = {};
            %test_scenario_name = {};
            
            % get the indecies of which items were selected or fill in default values
            if(~isempty(app.lb_train_data.Value))
                %train_idx = setdiff(app.lb_train_data.ItemsData, app.lb_train_data.Value);
                train_idx = app.lb_train_data.Value;
            else
                train_idx = app.lb_train_data.ItemsData;
            end
            
            if(~isempty(app.lb_test_data.Value))            
                test_idx = app.lb_test_data.Value;
            else
                test_idx = app.lb_test_data.ItemsData;
            end
            
            % save the files
            app.train_dm_files = app.lb_train_data.Items(train_idx);
            app.test_dm_files = app.lb_test_data.Items(test_idx);
            
%             % load depth maps
%             for idx=1:length(app.train_dm_files)
%                 
%                 %tmp_scene = strsplit(train_dm_files{idx},'/');
%                 %train_scenario_name{end+1,1} = tmp_scene{1};
%                 %tmp = imread(fullfile(train_data_path,train_dm_files{idx}));
%                 tmp = read_binary_lidar_data(fullfile(app.train_data_path,app.train_dm_files{idx}));
%                 tmp_size = size(tmp);
%                 app.norm_train_hist(idx,:) = histcounts(tmp(:),hist_bins)/(tmp_size(1)*tmp_size(2));
%                 
%             end
%             
%             app.sum_norm_train_hist = [sum(app.train_hist)];
            
%             if(~isempty(app.lb_train_data.Value))
%                 app.test_dm_files = app.lb_train_data.Items(app.lb_train_data.Value);
%                 for idx=1:length(app.test_dm_files)
%                     %tmp_scene = strsplit(test_dm_files{idx},'/');
%                     %test_scenario_name{end+1,1} = tmp_scene{1};    
%                     tmp = read_binary_lidar_data(fullfile(app.train_data_path,app.test_dm_files{idx}));
%                     tmp_size = size(tmp);
%                     app.norm_test_hist(idx,:) = histcounts(tmp(:),hist_bins)/(tmp_size(1)*tmp_size(2));
%                     
%                 end
%                 app.sum_norm_test_hist = [sum(app.norm_test_hist)];
%             else
%                 app.sum_norm_test_hist = zeros(size(app.sum_norm_train_hist));
%             end
              
            
            gen_hist(app);          
            
            plot_hist(app);
            
%             plot_step = 2000;
%             combined_hist = [sum_norm_train_hist; sum_norm_test_hist]';
%             
%             b = bar(app.plt_hist,[min_bin:hist_bin_step:max_bin-1], combined_hist);
%             
%             % X-Axis
%             xlim(app.plt_hist,[min_bin-2, max_bin+1]);
%             xticks(app.plt_hist,[min_bin:plot_step:max_bin-1]);
%             %xtickangle(app.plt_hist,90);
%             xlabel(app.plt_hist,strcat('Depth Map Value'),'fontweight','bold')
%             
%             % Y-Axis
%             ylabel(app.plt_hist,'Normalized Count','fontweight','bold')
%             ylim(app.plt_hist,[0 0.02]);
%             
%             b(1).FaceColor = 'b';
%             b(2).FaceColor = 'r';
%             
%             title(app.plt_hist,'Normalized Training/Testing Depth Map Distribution', 'fontweight','bold','FontSize',16);
%             legend(app.plt_hist,'Training Data','Testing Data', 'location','northeast')
%                       
%             app.sl_left.Value = 0;
%             app.sl_right.Value = max_bin;
            
        end

        % Value changed function: sl_left
        function sl_leftValueChanged(app, event)
            min_bin = floor(app.sl_left.Value);
            max_bin = ceil(app.sl_right.Value)+1;
            if(max_bin<min_bin)
                return;
            end
            %combined_hist = [app.sum_norm_train_hist; app.sum_norm_test_hist]';
            combined_hist = [app.train_hist_sum; app.test_hist_sum]';            
            xlim(app.plt_hist,[min_bin-2, max_bin]);
             
            %y_max = max(max(combined_hist(max(1,min_bin):min(max_bin,size(combined_hist,1)),:)));
            y_max = 10^ceil(log10(max(combined_hist(:))));
            if(y_max <= 0)
                ylim(app.plt_hist,[0 0.01]);
            else
                %ylim(app.plt_hist,[0 ceil(y_max*1000)/1000]);
                ylim(app.plt_hist,[1 y_max]);
            end
        end

        % Value changed function: sl_right
        function sl_rightValueChanged(app, event)
            min_bin = floor(app.sl_left.Value);
            max_bin = ceil(app.sl_right.Value)+1;
            if(max_bin<min_bin)
                return;
            end
            %combined_hist = [app.sum_norm_train_hist; app.sum_norm_test_hist]';
            combined_hist = [app.train_hist_sum; app.test_hist_sum]';            
            xlim(app.plt_hist,[min_bin-2, max_bin]);
            
            %y_max = max(max(combined_hist(max(1,min_bin):min(max_bin,size(combined_hist,1)),:)));
            y_max = 10^ceil(log10(max(combined_hist(:))));
            if(y_max <= 0)
                ylim(app.plt_hist,[0 0.01]);
            else
                %ylim(app.plt_hist,[0 ceil(y_max*1000)/1000]);
                ylim(app.plt_hist,[1 y_max]);                
            end
        end

        % Value changed function: nb_maxbin
        function nb_maxbinValueChanged(app, event)
            gen_hist(app);
            plot_hist(app);            
        end

        % Value changed function: nb_minbin
        function nb_minbinValueChanged(app, event)
            gen_hist(app);
            plot_hist(app);            
        end

        % Value changed function: nb_plot_step
        function nb_plot_stepValueChanged(app, event)
            gen_hist(app);
            plot_hist(app);            
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            
            % get the save file name
            file_filter = {'*.png','Image Files'; '*.fig','Figure Files'; '*.*','All Files' };
            [image_file, tmp_path] = uiputfile(file_filter, 'Enter save file', app.save_path);
            
            if(tmp_path == 0)
                return;
            end
            app.save_path = tmp_path;
            [~,~,ext] = fileparts(image_file);
            
            min_bin = app.nb_minbin.Value;
            max_bin = app.nb_maxbin.Value+1;
            
            x_lim = [floor(app.sl_left.Value), ceil(app.sl_right.Value)];
            
            fig=figure(100);
            set(fig,'position',([100,100,1200,600]),'color','w')
            hold on
            box on
            grid on

            plot_step = app.nb_plot_step.Value;
            
            %combined_hist = [app.sum_norm_train_hist; app.sum_norm_test_hist]';
            combined_hist = [app.train_hist_sum; app.test_hist_sum]';
            
            b = bar(fig.CurrentAxes,[min_bin:app.hist_bin_step:max_bin-1], combined_hist);
            
            % X-Axis
            %xlim(fig.CurrentAxes,[min_bin-2, max_bin+1]);
            %xticks(fig.CurrentAxes,[min_bin:plot_step:max_bin-1]);
            xlim(fig.CurrentAxes, [x_lim(1)-2, x_lim(2)+1]);
            xticks(fig.CurrentAxes,[x_lim(1):plot_step:x_lim(2)-1]);
            xtickangle(fig.CurrentAxes,90);
            xlabel(fig.CurrentAxes,strcat('Depth Map Value'),'fontweight','bold','FontSize',14)
            
            % Y-Axis
            set(fig.CurrentAxes,'fontweight','bold','FontSize',13, 'yscale','log');
            y_m = ceil(log10(max(combined_hist(:))));      %max(max(combined_hist(max(1,min_bin):min(max_bin,size(combined_hist,1)),:)));
            y_max = 10^y_m;
            ylabel(fig.CurrentAxes,'Depth Map Value Count','fontweight','bold','FontSize',14)
            ylim(fig.CurrentAxes,[1 y_max]);      %ylim(fig.CurrentAxes,[0 ceil(y_max*100)/100]);
            yticks(fig.CurrentAxes, 10.^[0:1:y_m]);
            yticklabels(fig.CurrentAxes, cellstr(num2str(round(log10(fig.CurrentAxes.YTick')), '10^{%d}')));

            % set the face color and bar widths
            b(1).FaceColor = 'b';
            b(1).BarWidth = 1.0;
            b(2).FaceColor = 'r';
            b(2).BarWidth = 1.0;
           
            
            title(fig.CurrentAxes,'Depth Map Distribution', 'fontweight','bold','FontSize',14);
            %legend(fig.CurrentAxes,'Training Data','Testing Data', 'location','northeast')
            legend(fig.CurrentAxes,'Training Data','Testing Data', 'location','southoutside', 'Orientation','horizontal')
            
            fig.CurrentAxes.Position = [0.06 0.17 0.92 0.77];
            
            if(strcmp(ext, '.png')==1)
                print(fig, '-dpng', fullfile(app.save_path, image_file));             
            elseif(strcmp(ext, '.fig') == 1)
                savefig(fig, fullfile(app.save_path, image_file));
            end
                        
            close(fig);
            
            uifigurepop(app);
            
        end

        % Button pushed function: SaveMatButton
        function SaveMatButtonPushed(app, event)

            file_filter = {'*.mat','MAT-files (*.mat)'; '*.*','All Files' };
            [mat_file, mat_path] = uiputfile(file_filter, 'Select Mat File Ave Location', app.save_path);
            
            if(mat_path == 0)
                return;
            end
            
            answer = inputdlg('Enter Dataset Name','');
            if(isempty(answer))
                name = ' ';
            else
                name = answer{1};
            end
            
            train_hist = app.train_hist;
            train_hist_sum = app.train_hist_sum;
            test_hist = app.test_hist;
            test_hist_sum = app.test_hist_sum;
            
            save(fullfile(mat_path, mat_file), 'train_hist', 'train_hist_sum', 'test_hist', 'test_hist_sum', 'name');
            
            uifigurepop(app);
            
        end

        % Button pushed function: TiledDistribtionButton
        function TiledDistribtionButtonPushed(app, event)
            
            
            plot_r = app.TiledRowsEditField.Value;
            plot_c = app.TiledColsEditField.Value;
            
            plot_w = 1/(2*plot_c);
            plot_h = 1/(2*plot_r);
            
            y_off = 0.05;
            x_off = 0.05;
            
            %loc = [(plot_r-r)*(plot_h+y)+y,(plot_r-r)*(plot_h+y)+y, ];
            
            % set up the image sizes
            index = 1;
            for r=1:plot_r
                for c=1:plot_c
                    x = (c-1)*(plot_w + x_off) + x_off;   
                    y = (plot_r - r)*(plot_h + y_off) + y_off;
                    
                    pos(index,:) = [x, y, x+plot_w, y+plot_h];  
                    index = index + 1;
                end
            end
            
            
            % get the file
            file_filter = {'*.png','Image Files';'*.*','All Files' };
            [image_file, tmp_path] = uiputfile(file_filter, 'Enter save file', app.save_path);
            
            if(tmp_path == 0)
                return;
            end
            app.save_path = tmp_path;
                       
            min_bin = app.nb_minbin.Value;
            max_bin = app.nb_maxbin.Value+1;
            
            fig=figure(100);
            set(fig,'position',([50,50,1200,700]),'color','w')
            hold on
            box on
            grid on

            plot_step = 100;    %app.nb_plot_step.Value;
            y_max = 10^ceil(log10(max(app.train_hist(:))));
            
            index = 1;
            
            for idx = 1:size(app.train_hist,1)
                                
                scene = regexp(app.train_dm_files{idx},'/','split');
                scene = scene{1};

                subplot(plot_r, plot_c, index)
                hold on
                box on
                grid on
                
                b = bar(fig.CurrentAxes,[min_bin:app.hist_bin_step:max_bin-1], app.train_hist(idx,:));
            
                % X-Axis
                xlim(fig.CurrentAxes,[min_bin-2, max_bin+1]);
                xticks(fig.CurrentAxes,[min_bin:plot_step:max_bin-1]);
                xtickangle(fig.CurrentAxes,90);
                %xlabel(fig.CurrentAxes,strcat('Depth Map Value'),'fontweight','bold','FontSize',13)
                
                % Y-Axis
                ylim(fig.CurrentAxes, [1 y_max]);      %ylim(fig.CurrentAxes,[0 ceil(y_max*100)/100]);
                %yticks(fig.CurrentAxes, [0:10:y_max]);
                %ylabel(fig.CurrentAxes,'Depth Map Value Count','fontweight','bold','FontSize',13)
                
                set(fig.CurrentAxes,'fontweight','bold','FontSize',13, 'yscale','log');
                
                % set the face color and bar widths
                b(1).FaceColor = 'b';
                b(1).BarWidth = 1.0;
                    
                    
                title(fig.CurrentAxes, scene, 'fontweight','bold','FontSize',13);
                %fig.CurrentAxes.Position = pos(index,:);
                index = index + 1;

            end
            
            print(fig, '-dpng', fullfile(app.save_path, image_file));
            close(fig);
            
            uifigurepop(app);
            
        end

        % Button pushed function: load_test_data_btn
        function load_test_data_btnButtonPushed(app, event)
            file_filter = {'*.txt','Text Files';'*.*','All Files' };
            
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.test_data_path);
            if(input_path == 0)
                return;
            end
            
            uifigurepop(app);
            
            app.test_input_file_tb.Value = input_file;
            
            input_data = parse_input_parameters(fullfile(input_path, input_file));
%            app.test_data_path = strcat(getenv('DATA_HOME'), '/', input_data{1}{1});
            app.test_data_path = input_data{1}{1};
            input_data(1) = [];
            
            tmp = cell(length(input_data),1);

            % get the depth map files
            for idx=1:length(input_data)    
                tmp_file = strsplit(input_data{idx}{3},{'_','.'});
                tmp{idx,1} = input_data{idx}{3};
            end
            dm_files = unique(tmp);
            
%             app.lb_train_data.Items = dm_files;
%             app.lb_train_data.ItemsData = [1:numel(dm_files)];
            
            app.lb_test_data.Items = dm_files;
            app.lb_test_data.ItemsData = [1:numel(dm_files)];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DepthMapDistributionToolUIFigure = uifigure('Visible', 'off');
            app.DepthMapDistributionToolUIFigure.Name = 'Depth Map Distribution Tool';
            app.plt_hist = uiaxes(app.DepthMapDistributionToolUIFigure);
            title(app.plt_hist, 'Depth Map Distribution')
            xlabel(app.plt_hist, 'Depth Map Value')
            ylabel(app.plt_hist, 'Depth Map Value Count')
            app.plt_hist.XTick = [0 127 255];
            app.plt_hist.XTickLabel = {'0'; '127'; '255'};
            app.TrainingDataLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TrainingDataLabel.Text = 'Training Data';
            app.lb_train_data = uilistbox(app.DepthMapDistributionToolUIFigure);
            app.lb_train_data.Items = {};
            app.lb_train_data.Multiselect = 'on';
            app.lb_train_data.Value = {};
            app.btn_load = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_loadButtonPushed, true);
            app.btn_load.Text = 'Load Training Data';
            app.btn_process = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.btn_process.ButtonPushedFcn = createCallbackFcn(app, @btn_processButtonPushed, true);
            app.btn_process.Text = 'Display Histogram';
            app.PlotLowerLimitLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.PlotLowerLimitLabel.Text = 'Plot Lower Limit';
            app.sl_left = uislider(app.DepthMapDistributionToolUIFigure);
            app.sl_left.MajorTicks = [];
            app.sl_left.MajorTickLabels = {};
            app.sl_left.ValueChangedFcn = createCallbackFcn(app, @sl_leftValueChanged, true);
            app.sl_left.MinorTicks = [];
            app.PlotUpperLimitLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.PlotUpperLimitLabel.Text = 'Plot Upper Limit';
            app.sl_right = uislider(app.DepthMapDistributionToolUIFigure);
            app.sl_right.MajorTicks = [];
            app.sl_right.MajorTickLabels = {};
            app.sl_right.ValueChangedFcn = createCallbackFcn(app, @sl_rightValueChanged, true);
            app.sl_right.MinorTicks = [];
            app.sl_right.Value = 100;
            app.MaxBinEditFieldLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.MaxBinEditFieldLabel.Text = 'Max Bin';
            app.nb_maxbin = uieditfield(app.DepthMapDistributionToolUIFigure, 'numeric');
            app.nb_maxbin.Limits = [1 Inf];
            app.nb_maxbin.ValueDisplayFormat = '%.0f';
            app.nb_maxbin.ValueChangedFcn = createCallbackFcn(app, @nb_maxbinValueChanged, true);
            app.nb_maxbin.Value = 255;
            app.MinBinEditFieldLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.MinBinEditFieldLabel.Text = 'Min Bin';
            app.nb_minbin = uieditfield(app.DepthMapDistributionToolUIFigure, 'numeric');
            app.nb_minbin.Limits = [0 Inf];
            app.nb_minbin.ValueDisplayFormat = '%.0f';
            app.nb_minbin.ValueChangedFcn = createCallbackFcn(app, @nb_minbinValueChanged, true);
            app.PlotStepEditFieldLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.PlotStepEditFieldLabel.Text = 'Plot Step';
            app.nb_plot_step = uieditfield(app.DepthMapDistributionToolUIFigure, 'numeric');
            app.nb_plot_step.Limits = [1 Inf];
            app.nb_plot_step.ValueDisplayFormat = '%.0f';
            app.nb_plot_step.ValueChangedFcn = createCallbackFcn(app, @nb_plot_stepValueChanged, true);
            app.nb_plot_step.Value = 10;
            app.TestDataLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TestDataLabel.Text = 'Test Data';
            app.lb_test_data = uilistbox(app.DepthMapDistributionToolUIFigure);
            app.lb_test_data.Items = {};
            app.lb_test_data.Multiselect = 'on';
            app.lb_test_data.Value = {};
            app.btn_save = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'Save Plot';
            app.TrainInputFileLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TrainInputFileLabel.Text = 'Train Input File';
            app.train_input_file_tb = uieditfield(app.DepthMapDistributionToolUIFigure, 'text');
            app.train_input_file_tb.Editable = 'off';
            app.SaveMatButton = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.SaveMatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatButtonPushed, true);
            app.SaveMatButton.Text = 'Save Mat';
            app.TiledDistribtionButton = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.TiledDistribtionButton.ButtonPushedFcn = createCallbackFcn(app, @TiledDistribtionButtonPushed, true);
            app.TiledDistribtionButton.Text = 'Tiled Distribtion';
            app.TiledRowsEditFieldLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TiledRowsEditFieldLabel.Text = 'Tiled Rows';
            app.TiledRowsEditField = uieditfield(app.DepthMapDistributionToolUIFigure, 'numeric');
            app.TiledRowsEditField.Limits = [1 Inf];
            app.TiledRowsEditField.Value = 4;
            app.TiledColsEditFieldLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TiledColsEditFieldLabel.Text = 'Tiled Cols';
            app.TiledColsEditField = uieditfield(app.DepthMapDistributionToolUIFigure, 'numeric');
            app.TiledColsEditField.Limits = [1 Inf];
            app.TiledColsEditField.Value = 8;
            app.load_test_data_btn = uibutton(app.DepthMapDistributionToolUIFigure, 'push');
            app.load_test_data_btn.ButtonPushedFcn = createCallbackFcn(app, @load_test_data_btnButtonPushed, true);
            app.load_test_data_btn.Text = 'Load Test Data';
            app.TestInputFileLabel = uilabel(app.DepthMapDistributionToolUIFigure);
            app.TestInputFileLabel.Text = 'Test Input File';
            app.test_input_file_tb = uieditfield(app.DepthMapDistributionToolUIFigure, 'text');
            app.test_input_file_tb.Editable = 'off';
            app.log_scale_cb = uicheckbox(app.DepthMapDistributionToolUIFigure);
            app.log_scale_cb.Text = 'Log Scale';
            app.log_scale_cb.Value = true;
            % Show the figure after all components are created
            app.DepthMapDistributionToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_histogram_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DepthMapDistributionToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DepthMapDistributionToolUIFigure)
        end
    end
end